classdef banana < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PredictButton           matlab.ui.control.Button
        OpenButton              matlab.ui.control.Button
        kematanganLabel         matlab.ui.control.Label
        namaLabel               matlab.ui.control.Label
        TingkatKematanganLabel  matlab.ui.control.Label
        NamaFileLabel           matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global image1;
            [filename, pathname] = uigetfile({'*.jpg'}, "Choose Banana Image");
            if filename ~= 0 
                fullname= strcat(pathname, filename);
                image1 = imread(fullname);
                imshow(image1, 'parent', app.UIAxes);
                
                app.namaLabel.Text = fullname;
            end
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            global image1;
            % Membaca image dan mengubah image menjadi double antara 0 dan 1.
            I = im2double(image1);
            
            % Mengambil nilai RGB dari image.
            Ir = I(:, :, 1);
            Ig = I(:, :, 2);
            Ib = I(:, :, 3);
            
            % Melakukan thresholding pada image untuk membuang background.
            % Thresholding dilakukan menggunakan nilai Blue, karena nilai Blue
            % rendah pada warna kulit pisang.
            Ibw = Ib < graythresh(Ib);
            It = cat(3, Ir .* Ibw, Ig .* Ibw, Ib .* Ibw);
            
            % Mengubah image menjadi bentuk HSV.
            Ihsv = rgb2hsv(It);
            
            % Mengambil nilai HSV dari image.
            Ih = Ihsv(:, :, 1);
            Is = Ihsv(:, :, 2);
            Iv = Ihsv(:, :, 3);
            
            % Mengambil rata-rata nilai HSV dari image tanpa background.
            Ihm = mean2(Ih(Ih > 0));
            Ism = mean2(Is(Ih > 0));
            Ivm = mean2(Iv(Ih > 0));
            
            
            %mengambil bentuk tulang dari gambar agar bisa melihat seberapa rumit
            %pembentuk dari benda pada gambar.
            tulang = bwmorph(Ibw, 'skel', Inf);
            bersih = bwmorph(tulang, 'spur', 20);
            bersih = bwmorph(bersih, 'spur', 20);
            
            %mengambil properties Area, BoundingBox, panjang dan lebar objek.
            props = regionprops(Ibw, {'Area','BoundingBox', 'MinorAxisLength', 'MajorAxisLength'});
            
            % Mengambil banyak "pulau" dalam objek
            numObj = numel(props);
            
            
            % Memberikan label prediksi pada image. Diambil nilai Hue 0.23 sebagai
            % batas atas dan 0.05 sebagai batas bawah, nilai di luar batas tersebut
            % menunjukkan bahwa gambar yang diberikan bukan merupakan pisang.
            % Selain dari Hue, kita juga mencari berdasarkan banyaknya pulau pulau
            % berdasarkan Black and white nya, jika banyak pulau dibawah 50 maka
            % dianggap sebagai pisang. Setelah itu melihat jumlah tulang pembentuk,
            % bahwa jika tulang pembentuk yang ditemukan < 4000, maka benda
            % tersebut termasuk pisang.
            checkRegProp = 1;
            if numObj < 50 
                sumGambar = sum(sum(bersih));
                if sumGambar < 4000 && sumGambar > 200
                    if Ihm > 0.23 || Ihm < 0.005
                        label = 'gambar invalid';
                    elseif Ihm > 0.14
                        label = 'mentah';
                    elseif Ihm > 0.13
                        label = 'setengah matang';
                    elseif Ihm > 0.11
                        label = 'matang';
                    else
                        label = 'terlalu matang';
                    end
                else
                   label = 'gambar invalid';
                   checkRegProp = 0;
                end
            else
                label = 'gambar invalid';
                checkRegProp = 0;
            end
            
            %Disini kita mencari Area dari pulau yang terbesar agar kita bisa
            %mendapatkan property dari benda yang menjadi pusat perhatian. Kita
            %juga mencari ratio dari pisang. 
            if checkRegProp == 1
                largestIndex = 1;
                for i = 1 : numObj
                   if props(i).Area == max([props.Area])
                       largestIndex = i;
                       picRatio = props(largestIndex).MajorAxisLength/props(largestIndex).MinorAxisLength;
                   end
                end
            end
            
            %Ratio dari pisang jika < 2 maka bisa dikatakn bukan pisang karena
            %pisang umumnya memiliki panjang yang lebih besar (lebih dari 2x lipat
            %lebarnya).
            if checkRegProp == 1
                if picRatio <2 && 1/picRatio < 2
                   label = 'gambar invalid'; 
                end
            end
            
            app.kematanganLabel.Text = label;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Image'; ''})
            xlabel(app.UIAxes, {''; ''})
            ylabel(app.UIAxes, {''; ''})
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.NamaFileLabel = uilabel(app.UIFigure);
            app.NamaFileLabel.Text = 'Nama File:';
            app.TingkatKematanganLabel = uilabel(app.UIFigure);
            app.TingkatKematanganLabel.Text = {'Tingkat Kematangan:'; ''};
            app.namaLabel = uilabel(app.UIFigure);
            app.namaLabel.Text = '<nama>';
            app.kematanganLabel = uilabel(app.UIFigure);
            app.kematanganLabel.Text = '<kematangan>';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = banana

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end